classdef ez_roi_detect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EZROIDetectionUIFigure         matlab.ui.Figure
        ROIDetectionLabel              matlab.ui.control.Label
        FilestoProcessLabel            matlab.ui.control.Label
        ProcessedFilesLabel            matlab.ui.control.Label
        FilestoProcessListBox          matlab.ui.control.ListBox
        ProcessedFilesListBox          matlab.ui.control.ListBox
        AddFilesButton                 matlab.ui.control.Button
        RemoveFileButton               matlab.ui.control.Button
        OpenFileButton                 matlab.ui.control.Button
        ClearListButton                matlab.ui.control.Button
        SettingsPanel                  matlab.ui.container.Panel
        ManualInitialRefinementCheckBox  matlab.ui.control.CheckBox
        SearchMethodLabel              matlab.ui.control.Label
        EstimatedofROIsLabel           matlab.ui.control.Label
        EstimatedROIWidthLabel         matlab.ui.control.Label
        MergeThresholdLabel            matlab.ui.control.Label
        SaveSettingsButton             matlab.ui.control.Button
        LoadSettingsButton             matlab.ui.control.Button
        SearchMethodDropDown           matlab.ui.control.DropDown
        EstimatedofROIsEditField       matlab.ui.control.NumericEditField
        EstimatedROIWidthEditField     matlab.ui.control.NumericEditField
        MergeThresholdEditField        matlab.ui.control.NumericEditField
        LoadSaveHelpButton             matlab.ui.control.Button
        DisplayROIBrowserCheckBox      matlab.ui.control.CheckBox
        DisplayROIContoursCheckBox     matlab.ui.control.CheckBox
        UseNeuronClassifierCheckBox    matlab.ui.control.CheckBox
        InitializationLabel            matlab.ui.control.Label
        InitializationDropDown         matlab.ui.control.DropDown
        DeconvolutionLabel             matlab.ui.control.Label
        DeconvolutionDropDown          matlab.ui.control.DropDown
        AutoregressionLabel            matlab.ui.control.Label
        AutoregressionDropDown         matlab.ui.control.DropDown
        FudgeFactorLabel               matlab.ui.control.Label
        FudgeFactorEditField           matlab.ui.control.NumericEditField
        SpatialDownsamplingLabel       matlab.ui.control.Label
        SpatialDownsamplingEditField   matlab.ui.control.NumericEditField
        TempDownsamplingLabel          matlab.ui.control.Label
        TempDownsamplingEditField      matlab.ui.control.NumericEditField
        TempIterationsLabel            matlab.ui.control.Label
        TempIterationsEditField        matlab.ui.control.NumericEditField
        InitializationHelpButton       matlab.ui.control.Button
        SearchMethodHelpButton         matlab.ui.control.Button
        DeconvolutionHelpButton        matlab.ui.control.Button
        AutoregressionHelpButton       matlab.ui.control.Button
        ManualInitialRefinementHelpButton  matlab.ui.control.Button
        UseNeuronClassifierHelpButton  matlab.ui.control.Button
        DisplayROIContoursHelpButton   matlab.ui.control.Button
        DisplayROIBrowserHelpButton    matlab.ui.control.Button
        EstimatedofROIsHelpButton      matlab.ui.control.Button
        EstimatedROIWidthHelpButton    matlab.ui.control.Button
        MergeThresholdHelpButton       matlab.ui.control.Button
        FudgeFactorHelpButton          matlab.ui.control.Button
        SpatialDownsamplingHelpButton  matlab.ui.control.Button
        TempDownsamplingHelpButton     matlab.ui.control.Button
        TempIterationsHelpButton       matlab.ui.control.Button
        SettingsLabel                  matlab.ui.control.Label
        RunROIDetectionButton          matlab.ui.control.Button
        HelpButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        roidet % internal storage of GUI settings
    end
    
    methods (Access = private)
        
        % Reads GUI, stores data into roidet variable
        function parse_roidet(app)
            app.roidet.init_method = app.InitializationDropDown.Value;
            app.roidet.search_method = app.SearchMethodDropDown.Value;
            app.roidet.deconv_method = app.DeconvolutionDropDown.Value;
            app.roidet.autoregression = app.AutoregressionDropDown.Value;
            
            app.roidet.manual_refine = app.ManualInitialRefinementCheckBox.Value;
            app.roidet.use_classifier = app.UseNeuronClassifierCheckBox.Value;
            app.roidet.check_contours = app.DisplayROIContoursCheckBox.Value;
            app.roidet.check_map = app.DisplayROIBrowserCheckBox.Value;
            
            app.roidet.input_components = app.EstimatedofROIsEditField.Value;
            app.roidet.input_width = app.EstimatedROIWidthEditField.Value;
            app.roidet.input_merge_thresh = app.MergeThresholdEditField.Value;
            app.roidet.input_fudge = app.FudgeFactorEditField.Value;
            app.roidet.input_space_down = app.SpatialDownsamplingEditField.Value;
            app.roidet.input_time_down = app.TempDownsamplingEditField.Value;
            app.roidet.input_time_iter = app.TempIterationsEditField.Value;
        end
        
        function write_roidet(app)
            app.InitializationDropDown.Value = app.roidet.init_method;
            app.SearchMethodDropDown.Value = app.roidet.search_method;
            app.DeconvolutionDropDown.Value = app.roidet.deconv_method;
            app.AutoregressionDropDown.Value = app.roidet.autoregression;
            
            app.ManualInitialRefinementCheckBox.Value = app.roidet.manual_refine;
            app.UseNeuronClassifierCheckBox.Value = app.roidet.use_classifier;
            app.DisplayROIContoursCheckBox.Value = app.roidet.check_contours;
            app.DisplayROIBrowserCheckBox.Value = app.roidet.check_map;
            
            app.EstimatedofROIsEditField.Value = app.roidet.input_components;
            app.EstimatedROIWidthEditField.Value = app.roidet.input_width;
            app.MergeThresholdEditField.Value = app.roidet.input_merge_thresh;
            app.FudgeFactorEditField.Value = app.roidet.input_fudge;
            app.SpatialDownsamplingEditField.Value = app.roidet.input_space_down;
            app.TempDownsamplingEditField.Value = app.roidet.input_time_down;
            app.TempIterationsEditField.Value = app.roidet.input_time_iter;
            
            drawnow
        end
        
        function [F_raw, F_inferred] = construct_traces(~, Y, A, C, b, f, options)
            Y = double(Y);
            b = double(b);
            C = double(C);
            f = double(f);
            nA = full(sqrt(sum(A.^2))');
            [K, ~] = size(C);
            A = A/spdiags(nA, 0, K, K);
            C = bsxfun(@times, C, nA(:));
            AY = mm_fun(A, Y);
            
            Y_r = (AY- (A'*A)*C - full(A'*b)*f) + C;
            [~, Df] = extract_DF_F(Y, A, C, [], options, AY);
            
            F_raw = Y_r ./ Df;
            F_inferred = C ./ Df;
        end
        
        function [Z_mod, F_baseline_mean, F_baseline_std, baseline_start_frame] = calc_zscore(~, F, frames)
            [F_baseline_std, baseline_start_frame] = min(movstd(F, frames, 0, 2, 'Endpoints', 'discard'), [], 2);
            F_baseline_mean = mean(F(:, baseline_start_frame:baseline_start_frame+frames-1), 2);
            Z_mod = (F - F_baseline_mean) ./ F_baseline_std;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filepath = fileparts([mfilename('fullpath') '.m']);
            settings_file = fullfile(filepath, 'ez_settings.mat');
            if isfile(settings_file)
                if ismember('roidet_settings', who('-file', settings_file))
                    load(fullfile(filepath, 'ez_settings.mat'), 'roidet_settings')
                    app.roidet = roidet_settings;
                    write_roidet(app)
                end
            end
            
            warning_text = '';
            if ~license('test', 'image_toolbox')
                warning_text = [warning_text newline 'Image Processing Toolbox'];
            end
            if ~license('test', 'distrib_computing_toolbox')
                warning_text = [warning_text newline 'Parallel Computing Toolbox'];
            end
            if ~license('test', 'signal_toolbox')
                warning_text = [warning_text newline 'Signal Processing Toolbox'];
            end
            if ~license('test', 'statistics_toolbox')
                warning_text = [warning_text newline 'Statistics and Machine Learning Toolbox'];
            end
            if ~isempty(warning_text)
                warning_text = ['It seems like you do not have the following MATLAB toolboxes installed: ' newline warning_text newline newline 'ROI Detection will not work without these toolboxes.'];
                msgbox(warning_text, 'Warning');
            end
            
            if ~exist('CNMFSetParms.m', 'file')
                warning_text = 'Cannot find CaImAn on MATLAB path. Please make sure you download CaImAn-MATLAB from https://github.com/porteralab/CaImAn-MATLAB and add it to MATLAB path. ROI Detection will not work without doing this.';
                msgbox(warning_text, 'Warning');
            end
        end

        % Button pushed function: AddFilesButton
        function AddFilesButtonPushed(app, event)
            supported_files = {'*.tif; *.tiff; *.avi; *.txt', ...
                'Supported Files (.tif, .tiff, .avi, .txt)';...
                '*.*', 'All Files'};
            
            [add_file, add_filepath] = uigetfile(supported_files, 'Choose file(s) to be processed.', 'MultiSelect', 'on');
            figure(app.EZROIDetectionUIFigure) % workaround to keep focus at UI window
            
            if ~iscell(add_file) && ~ischar(add_file)
                return
            end
            
            % Checks to see if only one item was added
            if ~iscell(add_file); add_file = cellstr(add_file); end
            
            for i = 1:length(add_file)
                full_add_files{i} = [add_filepath add_file{i}];
            end
            
            % Check for repeats, if not, add to list
            i = 0;
            while i < length(full_add_files)
                i = i + 1;
                cur_file = full_add_files{i};
                
                if sum(ismember(app.FilestoProcessListBox.Items, cur_file)) > 0 % If repeats, warning_text update
                    warning_text = ['File: ' cur_file ' is already on the list.'];
                    msgbox(warning_text, 'Warning');
                elseif strcmp(cur_file(end-3:end), '.txt')
                    fid = fopen(cur_file);
                    while 1
                        tline = fgetl(fid);
                        if ~ischar(tline), break, end
                        if isfile(tline)
                            [~, ~, file_ext] = fileparts(tline);
                            if strcmp(file_ext, '.tif') || strcmp(file_ext, '.tiff') || strcmp(file_ext, '.avi')
                                full_add_files = horzcat(full_add_files, cellstr(tline));
                            end
                        end
                    end
                    fclose(fid);
                else
                    app.FilestoProcessListBox.Items = horzcat(app.FilestoProcessListBox.Items, cellstr(cur_file)); drawnow
                end
            end
        end

        % Button pushed function: RemoveFileButton
        function RemoveFileButtonPushed(app, event)
            % Checks if anything is selected
            if isempty(app.FilestoProcessListBox.Value)
                return
            end
            
            [~, cur_pos] = ismember(app.FilestoProcessListBox.Value, app.FilestoProcessListBox.Items);
            
            % deal with selection
            if length(app.FilestoProcessListBox.Items) > 1
                if cur_pos == length(app.FilestoProcessListBox.Items)
                    app.FilestoProcessListBox.Value = app.FilestoProcessListBox.Items(cur_pos-1);
                else
                    app.FilestoProcessListBox.Value = app.FilestoProcessListBox.Items(cur_pos);
                end
            end
            
            app.FilestoProcessListBox.Items(cur_pos) = ''; drawnow % remove selection from list
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            % Checks if anything is selected
            if isempty(app.ProcessedFilesListBox.Value)
                return
            end
            
            file_string = app.ProcessedFilesListBox.Value;
            
            % Open file into workspace
            assignin('base', 'loadfile', file_string)
            evalin('base', 'load(loadfile)')
            evalin('base', 'clear loadfile')
        end

        % Button pushed function: ClearListButton
        function ClearListButtonPushed(app, event)
            app.ProcessedFilesListBox.Items = {}; drawnow
        end

        % Button pushed function: RunROIDetectionButton
        function RunROIDetectionButtonPushed(app, event)
            if isempty(app.FilestoProcessListBox.Items)
                warning_text = 'No file has been added yet!';
                msgbox(warning_text, 'Warning');
                return
            end
            
            app.RunROIDetectionButton.Enable = 'off'; drawnow
            
            parse_roidet(app)
            
            K = app.roidet.input_components; % number of components to be found
            tau = round(app.roidet.input_width/2); % std of gaussian kernel (half size of neuron)
            
            switch app.roidet.autoregression
                case 'No dynamics'
                    p = 0;
                case 'Decay'
                    p = 1;
                case 'Rise and Decay'
                    p = 2;
            end
            
            switch app.roidet.init_method
                case 'Greedy'
                    init_method = 'greedy';
                case 'Sparse NMF'
                    init_method = 'sparse_NMF';
            end
            
            switch app.roidet.search_method
                case 'Ellipse'
                    search_method = 'ellipse';
                case 'Dilate'
                    search_method = 'dilate';
            end
            
            switch app.roidet.deconv_method
                case 'Constrained FOOPSI - CVX'
                    deconv_method='constrained_foopsi';
                    method='cvx';
                case 'Constrained FOOPSI - SPGL1'
                    deconv_method='constrained_foopsi';
                    method='spgl1';
                case 'MCEM FOOPSI - CVX'
                    deconv_method='MCEM_foopsi';
                    method='cvx';
                case 'MCEM FOOPSI - SPGL1'
                    deconv_method='MCEM_foopsi';
                    method='spgl1';
                case 'MCMC'
                    deconv_method='MCMC';
                    method='cvx';
            end
            
            % Move files to process highlight to first position
            app.FilestoProcessListBox.Value = app.FilestoProcessListBox.Items(1);
            
            % Find number of files to be run
            file_num = length(app.FilestoProcessListBox.Items);
            
            for i = 1:file_num
                filename = app.FilestoProcessListBox.Value;
                disp(['Starting ROI detection for ' filename]);
                
                % ======== Load file ======================================
                
                Y = read_file(filename);
                if ~isa(Y, 'single'); Y = single(Y); end % convert to single
                
                [d1, d2, T] = size(Y); % dimensions of dataset
                d = d1*d2; % total number of pixels
                
                % ======== Set parameters =================================
                
                options = CNMFSetParms(...                           % Check CNMFSetParms.m for more details
                    'd1', d1, 'd2', d2, ...                          % Dimensions of datasets
                    'ssub', app.roidet.input_space_down, ...         % Spatial downsampling
                    'tsub', app.roidet.input_time_down, ...          % Temporal downsampling
                    'init_method', init_method, ...                  % Initialization method
                    'search_method', search_method, ...              % Search locations when updating spatial components
                    'dist', 3, ...                                   % Expansion factor of ellipse
                    'deconv_method', deconv_method, ...              % Activity deconvolution method
                    'method', method, ...                            % Method for solving convex problems
                    'temporal_iter', app.roidet.input_time_iter, ... % Number of block-coordinate descent steps
                    'fudge_factor', app.roidet.input_fudge, ...      % Bias correction for autoregression coefficients
                    'merge_thr', app.roidet.input_merge_thresh, ...  % Merging threshold
                    'p', p, ...                                      % Order of AR dynamics
                    'gSig', tau, ...                                 % Half size of neuron
                    'nb', 2, ...                                     % Number of background components
                    'min_SNR', 3, ...                                % Minimum SNR threshold
                    'cnn_thr', 0.2, ...                              % Threshold for CNN classifier
                    'bas_nonneg', 0);                                % Allow a negative baseline
                
                % ======== Data pre-processing=============================
                
                [P, Y] = preprocess_data(Y, p, options);
                
                % ======== Initialization spatial components ==============
                
                [Ain, Cin, bin, fin, center] = initialize_components(Y, K, tau, options, P);
                Cn = correlation_image(Y);
                
                % ======== Manually refine components =====================
                
                if app.roidet.manual_refine
                    [Ain, Cin, ~] = manually_refine_components(Y, Ain, Cin, center, Cn, tau, options);
                end
                
                % ======== Update spatial and temporal components =========
                
                Yr = reshape(Y, d, T);
                [A, b, Cin] = update_spatial_components(Yr, Cin, fin, [Ain, bin], P, options);
                P.p = 0; % Turn off autoregression dynamics temporarily for speed
                [C, f, P, S, YrA] = update_temporal_components(Yr, A, b, Cin, fin, P, options);
                
                % ======== Classification =================================
                
                if app.roidet.use_classifier
                    
                    % classify components
                    rval_space = classify_comp_corr(Y, A, C, b, f, options);
                    ind_corr = rval_space > options.space_thresh; % components that pass the correlation test
                    % this test will keep processes
                    
                    % further classification with cnn_classifier
                    try  % matlab 2017b or later is needed
                        [ind_cnn, ~] = cnn_classifier(A, [d1, d2], 'cnn_model', options.cnn_thr);
                    catch
                        ind_cnn = true(size(A, 2), 1); % components that pass the CNN classifier
                    end
                    
                    % event exceptionality
                    
                    fitness = compute_event_exceptionality(C+YrA, options.N_samples_exc, options.robust_std);
                    ind_exc = (fitness < options.min_fitness);
                    
                    % select components
                    
                    keep = (ind_corr | ind_cnn) & ind_exc;
                    
                    % display kept and discarded components
                    figure;
                    subplot(121); montage(extract_patch(A(:, keep), [d1, d2], [30, 30]), 'DisplayRange', [0, 0.15]);
                    title('Kept Components');
                    subplot(122); montage(extract_patch(A(:, ~keep), [d1, d2], [30, 30]), 'DisplayRange', [0, 0.15])
                    title('Discarded Components');
                    
                    A = A(:, keep);
                    C = C(keep, :);
                end
                
                % ======== Merge found components =========================
                
                [Am, Cm, ~, ~, Pm, ~] = merge_components(Yr, A, b, C, f, P, S, options);
                
                % ======== Refine estimates excluding rejected components =
                
                Pm.p = p; % Restore autoregression dynamics value
                [A2, b2, C2] = update_spatial_components(Yr, Cm, f, [Am, b], Pm, options);
                [C2, f2, P2, S2, ~] = update_temporal_components(Yr, A2, b2, C2, f, Pm, options);
                
                % ======== Plotting =======================================
                
                [A_or, C_or, S_or, ~] = order_ROIs(A2, C2, S2, P2); % order ROIs
                
                % plot ROI contours
                if app.roidet.check_contours
                    figure; plot_contours(A_or, Cn, options, 1);
                end
                
                % display ROI map
                if app.roidet.check_map
                    plot_components_GUI(Yr, A_or, C_or, b2, f2, Cn, options);
                end
                
                % ======== Save data ======================================
                
                % Calculate traces to be saved
                [F_raw, F_inferred] = construct_traces(app, Yr, A_or, C_or, b2, f2, options);
                S_deconv = S_or;
                spatial_comp = A_or;
                ROI_center = zeros(size(spatial_comp, 2), 2);
                for cur_ROI = 1:size(spatial_comp, 2)
                    single_ROI = full(reshape(spatial_comp(:, cur_ROI), size(Cn, 1), size(Cn, 2)));
                    s = regionprops(true(size(single_ROI)), single_ROI, 'WeightedCentroid');
                    ROI_center(cur_ROI, :) = s.WeightedCentroid;
                end
                
                % Save MAT file
                roidet_settings = app.roidet;
                
                filename_mat = [filename(1:end-4) '.mat'];
                if isfile(filename_mat)
                    if ismember('F_raw', who('-file', filename_mat))
                        msgbox(['It looks like file ' filename_mat ' already have saved ROI detection data. Will improvise a different file name to avoid overwriting.'], 'Warning')
                        filename_mat = [filename(1:end-4) '_' datestr(now, 30) '.mat'];
                        save(filename_mat, 'Cn', 'spatial_comp', 'F_raw', 'F_inferred', 'S_deconv', 'ROI_center', 'options', 'roidet_settings');
                    else
                        save(filename_mat, 'Cn', 'spatial_comp', 'F_raw', 'F_inferred', 'S_deconv', 'ROI_center', 'options', 'roidet_settings', '-append');
                    end
                else
                    save(filename_mat, 'Cn', 'spatial_comp', 'F_raw', 'F_inferred', 'S_deconv', 'ROI_center', 'options', 'roidet_settings');
                end
                
                % Update file lists
                app.FilestoProcessListBox.Items(1) = '';
                app.ProcessedFilesListBox.Items = horzcat(app.ProcessedFilesListBox.Items, cellstr(filename_mat)); drawnow
            end
            
            app.RunROIDetectionButton.Enable = 'on'; drawnow
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web('https://github.com/porteralab/EZcalcium/wiki/ROI-Detection')
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            [filename, filepath] = uiputfile('*.mat');
            figure(app.EZROIDetectionUIFigure) % workaround to keep focus at UI window
            
            % Check if anything was selected
            if filename == 0
                return
            end
            
            parse_roidet(app)
            roidet_settings = app.roidet;
            
            full_filename = [filepath filename];
            save(full_filename, 'roidet_settings');
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.mat');
            figure(app.EZROIDetectionUIFigure) % workaround to keep focus at UI window
            
            % Check if anything was selected
            if filename == 0
                return
            end
            
            full_filename = [filepath filename];
            
            % Check if file is valid
            if ~ismember('roidet_settings', who('-file', full_filename))
                warning_text = 'The selected file is not a valid settings file.';
                msgbox(warning_text, 'Warning')
                return
            end
            
            load(full_filename, 'roidet_settings');
            app.roidet = roidet_settings;
            write_roidet(app)
        end

        % Button pushed function: InitializationHelpButton
        function InitializationHelpButtonPushed(app, event)
            msgbox("Initialization methods for providing an initial estimate of spatial and temporal components: Greedy is recommended for videos of neuronal somata. It relies heavily on spatial components and generally runs much faster; Sparse NMF is recommended for more complex structures, such as dendrites, dendritic spines, or axons.", "Help", 'replace')
        end

        % Button pushed function: SearchMethodHelpButton
        function SearchMethodHelpButtonPushed(app, event)
            msgbox("Search Method determines the spatial components (location) of ROIs: Ellipse assumes components have an ellipsoid shape, such as for neuronal somata; Dilate can be used with either ellipsoid or non-ellipsoid ROIs, but generally takes longer.", "Help", 'replace')
        end

        % Button pushed function: DeconvolutionHelpButton
        function DeconvolutionHelpButtonPushed(app, event)
            msgbox("Deconvolution determines the method for translating activity-induced changes in the fluorescence intensity of the indicator into approximate firing rates. If you are imaging an organism that does not produce action potentials, set this to the fastest setting available. Constrained FOOPSI is generally recommended and, with CVX, it is typically the fastest method of deconvolution when working with high signal-to-noise traces. Constrained FOOPSI – SPGL1 works better with medium-to-low signal-to-noise traces. MCMC is a fully-Bayesian deconvolution method that is computationally intensive and is recommended when higher precision is required. MCEM alternates between the listed Constrained FOOPSI deconvolution and MCMC to update time constants, which is significantly faster than MCMC alone. Note that additional installation of CVX is needed when using MCMC. Users can download CVX at http://cvxr.com/cvx/download/, and follow the instructions.", "Help", 'replace')
        end

        % Button pushed function: AutoregressionHelpButton
        function AutoregressionHelpButtonPushed(app, event)
            msgbox("Autoregression is used to estimate the calcium indicator kinetics. Rise and Decay estimates both the rise and decay kinetics of the calcium indicator and incorporates them when extracting fluorescence traces and deconvolving the signal. Due to the difficulty in detecting fast rise times, using Rise and Decay may result in overfit data if the imaging was performed at low temporal resolution (< 16 Hz). Decay estimates only the decay kinetics of the calcium indicator and is the recommended setting for lower temporal resolution imaging. No Dynamics will produce only raw traces and will not perform deconvolution.", "Help", 'replace')
        end

        % Button pushed function: EstimatedofROIsHelpButton
        function EstimatedofROIsHelpButtonPushed(app, event)
            msgbox("Estimated # of ROIs is the estimated maximum number of components in the field of view. This must be set to a minimum value of 1. If you want to perform fully manual ROI selection, set Estimated Components to 1 and check the box to enable Manual Initial Refinement. When the manual refinement step starts, delete the initial automatically detected ROI. If no components are determined to be similar enough to be merged, the most likely result is that the number set for Estimated Components is the number that will be initially detected.", "Help", 'replace')
        end

        % Button pushed function: EstimatedROIWidthHelpButton
        function EstimatedROIWidthHelpButtonPushed(app, event)
            msgbox("Estimated ROI Width is the estimated width, in pixels, of your components. If you have a simple ROI shape, such as a cell soma, you can use the width of the entire ROI as your component width.", "Help", 'replace')
        end

        % Button pushed function: MergeThresholdHelpButton
        function MergeThresholdHelpButtonPushed(app, event)
            msgbox("Merge Threshold is the threshold at which two components will be merged into a single ROI. Components that share a correlation coefficient above Merge Threshold will be merged into a single ROI.", "Help", 'replace')
        end

        % Button pushed function: FudgeFactorHelpButton
        function FudgeFactorHelpButtonPushed(app, event)
            msgbox("Fudge Factor is useful for estimating time constants of very noisy data, in particular those with low temporal resolution (slow frame rate). The value indicates a multiplicative bias correction for time constants of each ROI during deconvolution. Fudge Factor should generally be set to 0.95-1. A value of 1 indicates that no bias correction will be performed.", "Help", 'replace')
        end

        % Button pushed function: SpatialDownsamplingHelpButton
        function SpatialDownsamplingHelpButtonPushed(app, event)
            msgbox("Spatial Downsampling will downsample the spatial resolution of a video by a factor set here. The value entered should be a positive integer. A value of 1 means that no downsampling will be performed. This is useful for rapidly troubleshooting the settings on videos with a very large field of view.", "Help", 'replace')
        end

        % Button pushed function: TempDownsamplingHelpButton
        function TempDownsamplingHelpButtonPushed(app, event)
            msgbox("Temporal Downsampling is similar to Spatial Downsampling, except it downsamples the temporal resolution. This is useful for optimizing settings on very long, high frame rate (> 15 Hz) videos.", "Help", 'replace')
        end

        % Button pushed function: TempIterationsHelpButton
        function TempIterationsHelpButtonPushed(app, event)
            msgbox("Temporal Iterations is the number of iterations that will be performed to calculate the temporal components of ROIs. This should be set to at least 2 when not rapidly testing other parameters.", "Help", 'replace')
        end

        % Button pushed function: ManualInitialRefinementHelpButton
        function ManualInitialRefinementHelpButtonPushed(app, event)
            msgbox("Manual Initial Refinement adds an additional step following initialization to manually add or remove ROIs. ROIs can also be removed in the step ROI Refinement. To fully automate the process, it is recommended to optimize your settings to slightly overestimate the number of ROIs and then remove erroneous ROIs later. This step is included as an option for particularly troublesome files and for those who prefer semi-automated ROI selection. Initial spatial components will be displayed in a new figure. The center of estimates in ROIs is highlighted with a magenta circle, surrounded by the boundary of the ROI. To manually add an ROI, left click with the mouse where you want to add the center of an ROI. The boundary of the ROI will be automatically computed and drawn. To manually remove an ROI, right click on any ROI center. Hit the ENTER/RETURN key to continue ROI detection.", "Help", 'replace')
        end

        % Button pushed function: UseNeuronClassifierHelpButton
        function UseNeuronClassifierHelpButtonPushed(app, event)
            msgbox("Use Neuron Classifier utilizes CaImAn’s built-in neuron classifier to exclude potentially erroneous ROIs based on their shapes. Leave this option unselected if desired ROIs are not neuron cell bodies.", "Help", 'replace')
        end

        % Button pushed function: DisplayROIContoursHelpButton
        function DisplayROIContoursHelpButtonPushed(app, event)
            msgbox("Display ROI Contours generates a map with all the ROI boundaries, each labeled with the same ROI number as was used in the data.", "Help", 'replace')
        end

        % Button pushed function: DisplayROIBrowserHelpButton
        function DisplayROIBrowserHelpButtonPushed(app, event)
            msgbox("Display ROI Browser shows extracted raw fluorescence data, the inferred trace generated, and the ROI shape and location.", "Help", 'replace')
        end

        % Button pushed function: LoadSaveHelpButton
        function LoadSaveHelpButtonPushed(app, event)
            msgbox("Save Settings allows you to manually save all settings under a specific file name of your choosing. Settings are saved as .mat files. Note that EZcalcium will automatically save the settings into the .mat data file, so manually saving the settings is usually not necessary. Load Settings allows you to load any previously saved settings. You can load the automatically generated .mat file (which is the same file that saves ROI Detection and ROI Refinement results) to check the settings used to generate the results.", "Help", 'replace')
        end

        % Close request function: EZROIDetectionUIFigure
        function EZROIDetectionUIFigureCloseRequest(app, event)
            parse_roidet(app)
            roidet_settings = app.roidet;
            filepath = fileparts([mfilename('fullpath') '.m']);
            if isfile(fullfile(filepath, 'ez_settings.mat'))
                save(fullfile(filepath, 'ez_settings.mat'), 'roidet_settings', '-append')
            else
                save(fullfile(filepath, 'ez_settings.mat'), 'roidet_settings')
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EZROIDetectionUIFigure = uifigure('Visible', 'off');
            app.EZROIDetectionUIFigure.Name = 'EZ ROI Detection';
            app.EZROIDetectionUIFigure.CloseRequestFcn = createCallbackFcn(app, @EZROIDetectionUIFigureCloseRequest, true);
            app.ROIDetectionLabel = uilabel(app.EZROIDetectionUIFigure);
            app.ROIDetectionLabel.Text = 'ROI Detection';
            app.FilestoProcessLabel = uilabel(app.EZROIDetectionUIFigure);
            app.FilestoProcessLabel.Text = 'Files to Process';
            app.ProcessedFilesLabel = uilabel(app.EZROIDetectionUIFigure);
            app.ProcessedFilesLabel.Text = 'Processed Files';
            app.FilestoProcessListBox = uilistbox(app.EZROIDetectionUIFigure);
            app.FilestoProcessListBox.Items = {};
            app.FilestoProcessListBox.Value = {};
            app.ProcessedFilesListBox = uilistbox(app.EZROIDetectionUIFigure);
            app.ProcessedFilesListBox.Items = {};
            app.ProcessedFilesListBox.Value = {};
            app.AddFilesButton = uibutton(app.EZROIDetectionUIFigure, 'push');
            app.AddFilesButton.ButtonPushedFcn = createCallbackFcn(app, @AddFilesButtonPushed, true);
            app.AddFilesButton.Text = {'Add Files...'; ''};
            app.RemoveFileButton = uibutton(app.EZROIDetectionUIFigure, 'push');
            app.RemoveFileButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveFileButtonPushed, true);
            app.RemoveFileButton.Text = 'Remove File';
            app.OpenFileButton = uibutton(app.EZROIDetectionUIFigure, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.ClearListButton = uibutton(app.EZROIDetectionUIFigure, 'push');
            app.ClearListButton.ButtonPushedFcn = createCallbackFcn(app, @ClearListButtonPushed, true);
            app.ClearListButton.Text = 'Clear List';
            app.SettingsPanel = uipanel(app.EZROIDetectionUIFigure);
            app.ManualInitialRefinementCheckBox = uicheckbox(app.SettingsPanel);
            app.ManualInitialRefinementCheckBox.Text = 'Manual Initial Refinement';
            app.SearchMethodLabel = uilabel(app.SettingsPanel);
            app.SearchMethodLabel.Text = 'Search Method';
            app.EstimatedofROIsLabel = uilabel(app.SettingsPanel);
            app.EstimatedofROIsLabel.Text = 'Estimated # of ROIs';
            app.EstimatedROIWidthLabel = uilabel(app.SettingsPanel);
            app.EstimatedROIWidthLabel.Text = 'Estimated ROI Width';
            app.MergeThresholdLabel = uilabel(app.SettingsPanel);
            app.MergeThresholdLabel.Text = 'Merge Threshold';
            app.SaveSettingsButton = uibutton(app.SettingsPanel, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.LoadSettingsButton = uibutton(app.SettingsPanel, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.SearchMethodDropDown = uidropdown(app.SettingsPanel);
            app.SearchMethodDropDown.Items = {'Ellipse', 'Dilate'};
            app.SearchMethodDropDown.Value = 'Ellipse';
            app.EstimatedofROIsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.EstimatedofROIsEditField.Value = 80;
            app.EstimatedROIWidthEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.EstimatedROIWidthEditField.Value = 20;
            app.MergeThresholdEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.MergeThresholdEditField.Value = 0.9;
            app.LoadSaveHelpButton = uibutton(app.SettingsPanel, 'push');
            app.LoadSaveHelpButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSaveHelpButtonPushed, true);
            app.LoadSaveHelpButton.Text = '?';
            app.DisplayROIBrowserCheckBox = uicheckbox(app.SettingsPanel);
            app.DisplayROIBrowserCheckBox.Text = 'Display ROI Browser';
            app.DisplayROIContoursCheckBox = uicheckbox(app.SettingsPanel);
            app.DisplayROIContoursCheckBox.Text = 'Display ROI Contours';
            app.UseNeuronClassifierCheckBox = uicheckbox(app.SettingsPanel);
            app.UseNeuronClassifierCheckBox.Text = 'Use Neuron Classifier';
            app.InitializationLabel = uilabel(app.SettingsPanel);
            app.InitializationLabel.Text = 'Initialization';
            app.InitializationDropDown = uidropdown(app.SettingsPanel);
            app.InitializationDropDown.Items = {'Greedy', 'Sparse NMF'};
            app.InitializationDropDown.Value = 'Greedy';
            app.DeconvolutionLabel = uilabel(app.SettingsPanel);
            app.DeconvolutionLabel.Text = 'Deconvolution';
            app.DeconvolutionDropDown = uidropdown(app.SettingsPanel);
            app.DeconvolutionDropDown.Items = {'Constrained FOOPSI - CVX', 'Constrained FOOPSI - SPGL1', 'MCEM FOOPSI - CVX', 'MCEM FOOPSI - SPGL1', 'MCMC'};
            app.DeconvolutionDropDown.Value = 'Constrained FOOPSI - CVX';
            app.AutoregressionLabel = uilabel(app.SettingsPanel);
            app.AutoregressionLabel.Text = 'Autoregression';
            app.AutoregressionDropDown = uidropdown(app.SettingsPanel);
            app.AutoregressionDropDown.Items = {'No dynamics', 'Decay', 'Rise and Decay'};
            app.AutoregressionDropDown.Value = 'Decay';
            app.FudgeFactorLabel = uilabel(app.SettingsPanel);
            app.FudgeFactorLabel.Text = 'Fudge Factor';
            app.FudgeFactorEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.FudgeFactorEditField.Value = 0.98;
            app.SpatialDownsamplingLabel = uilabel(app.SettingsPanel);
            app.SpatialDownsamplingLabel.Text = 'Spatial Downsampling';
            app.SpatialDownsamplingEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.SpatialDownsamplingEditField.Value = 1;
            app.TempDownsamplingLabel = uilabel(app.SettingsPanel);
            app.TempDownsamplingLabel.Text = 'Temp. Downsampling';
            app.TempDownsamplingEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.TempDownsamplingEditField.Value = 1;
            app.TempIterationsLabel = uilabel(app.SettingsPanel);
            app.TempIterationsLabel.Text = 'Temp. Iterations';
            app.TempIterationsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.TempIterationsEditField.Value = 5;
            app.InitializationHelpButton = uibutton(app.SettingsPanel, 'push');
            app.InitializationHelpButton.ButtonPushedFcn = createCallbackFcn(app, @InitializationHelpButtonPushed, true);
            app.InitializationHelpButton.Text = '?';
            app.SearchMethodHelpButton = uibutton(app.SettingsPanel, 'push');
            app.SearchMethodHelpButton.ButtonPushedFcn = createCallbackFcn(app, @SearchMethodHelpButtonPushed, true);
            app.SearchMethodHelpButton.Text = '?';
            app.DeconvolutionHelpButton = uibutton(app.SettingsPanel, 'push');
            app.DeconvolutionHelpButton.ButtonPushedFcn = createCallbackFcn(app, @DeconvolutionHelpButtonPushed, true);
            app.DeconvolutionHelpButton.Text = '?';
            app.AutoregressionHelpButton = uibutton(app.SettingsPanel, 'push');
            app.AutoregressionHelpButton.ButtonPushedFcn = createCallbackFcn(app, @AutoregressionHelpButtonPushed, true);
            app.AutoregressionHelpButton.Text = '?';
            app.ManualInitialRefinementHelpButton = uibutton(app.SettingsPanel, 'push');
            app.ManualInitialRefinementHelpButton.ButtonPushedFcn = createCallbackFcn(app, @ManualInitialRefinementHelpButtonPushed, true);
            app.ManualInitialRefinementHelpButton.Text = '?';
            app.UseNeuronClassifierHelpButton = uibutton(app.SettingsPanel, 'push');
            app.UseNeuronClassifierHelpButton.ButtonPushedFcn = createCallbackFcn(app, @UseNeuronClassifierHelpButtonPushed, true);
            app.UseNeuronClassifierHelpButton.Text = '?';
            app.DisplayROIContoursHelpButton = uibutton(app.SettingsPanel, 'push');
            app.DisplayROIContoursHelpButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayROIContoursHelpButtonPushed, true);
            app.DisplayROIContoursHelpButton.Text = '?';
            app.DisplayROIBrowserHelpButton = uibutton(app.SettingsPanel, 'push');
            app.DisplayROIBrowserHelpButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayROIBrowserHelpButtonPushed, true);
            app.DisplayROIBrowserHelpButton.Text = '?';
            app.EstimatedofROIsHelpButton = uibutton(app.SettingsPanel, 'push');
            app.EstimatedofROIsHelpButton.ButtonPushedFcn = createCallbackFcn(app, @EstimatedofROIsHelpButtonPushed, true);
            app.EstimatedofROIsHelpButton.Text = '?';
            app.EstimatedROIWidthHelpButton = uibutton(app.SettingsPanel, 'push');
            app.EstimatedROIWidthHelpButton.ButtonPushedFcn = createCallbackFcn(app, @EstimatedROIWidthHelpButtonPushed, true);
            app.EstimatedROIWidthHelpButton.Text = '?';
            app.MergeThresholdHelpButton = uibutton(app.SettingsPanel, 'push');
            app.MergeThresholdHelpButton.ButtonPushedFcn = createCallbackFcn(app, @MergeThresholdHelpButtonPushed, true);
            app.MergeThresholdHelpButton.Text = '?';
            app.FudgeFactorHelpButton = uibutton(app.SettingsPanel, 'push');
            app.FudgeFactorHelpButton.ButtonPushedFcn = createCallbackFcn(app, @FudgeFactorHelpButtonPushed, true);
            app.FudgeFactorHelpButton.Text = '?';
            app.SpatialDownsamplingHelpButton = uibutton(app.SettingsPanel, 'push');
            app.SpatialDownsamplingHelpButton.ButtonPushedFcn = createCallbackFcn(app, @SpatialDownsamplingHelpButtonPushed, true);
            app.SpatialDownsamplingHelpButton.Text = '?';
            app.TempDownsamplingHelpButton = uibutton(app.SettingsPanel, 'push');
            app.TempDownsamplingHelpButton.ButtonPushedFcn = createCallbackFcn(app, @TempDownsamplingHelpButtonPushed, true);
            app.TempDownsamplingHelpButton.Text = '?';
            app.TempIterationsHelpButton = uibutton(app.SettingsPanel, 'push');
            app.TempIterationsHelpButton.ButtonPushedFcn = createCallbackFcn(app, @TempIterationsHelpButtonPushed, true);
            app.TempIterationsHelpButton.Text = '?';
            app.SettingsLabel = uilabel(app.EZROIDetectionUIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.RunROIDetectionButton = uibutton(app.EZROIDetectionUIFigure, 'push');
            app.RunROIDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @RunROIDetectionButtonPushed, true);
            app.RunROIDetectionButton.Text = 'Run ROI Detection';
            app.HelpButton = uibutton(app.EZROIDetectionUIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.EZROIDetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ez_roi_detect

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EZROIDetectionUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.EZROIDetectionUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EZROIDetectionUIFigure)
        end
    end
end