classdef ez_roi_match < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EZROIMatchingUIFigure         matlab.ui.Figure
        ROIMatchingLabel              matlab.ui.control.Label
        DataFilesLabel                matlab.ui.control.Label
        DataFilesListBox              matlab.ui.control.ListBox
        AddFilesButton                matlab.ui.control.Button
        MovedownButton                matlab.ui.control.Button
        ClearListButton               matlab.ui.control.Button
        SettingsPanel                 matlab.ui.container.Panel
        UseRefinedROIsCheckBox        matlab.ui.control.CheckBox
        OverlapThresholdLabel         matlab.ui.control.Label
        SaveSettingsButton            matlab.ui.control.Button
        LoadSettingsButton            matlab.ui.control.Button
        OverlapThresholdEditField     matlab.ui.control.NumericEditField
        UseRefinedROIsHelpButton      matlab.ui.control.Button
        PlotMatchingResultHelpButton  matlab.ui.control.Button
        OverlapThresholdHelpButton    matlab.ui.control.Button
        LoadSaveHelpButton            matlab.ui.control.Button
        PlotMatchingResultCheckBox    matlab.ui.control.CheckBox
        SettingsLabel                 matlab.ui.control.Label
        RunROIMatchingButton          matlab.ui.control.Button
        HelpButton                    matlab.ui.control.Button
        MoveupButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        roimat % internal storage of GUI settings
        roidata % loaded ROI data
    end
    
    methods (Access = private)
        
        function parse_roimat(app)
            app.roimat.use_refined = app.UseRefinedROIsCheckBox.Value;
            app.roimat.plot_result = app.PlotMatchingResultCheckBox.Value;
            app.roimat.overlap_thr = app.OverlapThresholdEditField.Value;
        end
        
        function write_roimat(app)
            app.UseRefinedROIsCheckBox.Value = app.roimat.use_refined;
            app.PlotMatchingResultCheckBox.Value = app.roimat.plot_result;
            app.OverlapThresholdEditField.Value = app.roimat.overlap_thr;
            
            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filepath = fileparts([mfilename('fullpath') '.m']);
            settings_file = fullfile(filepath, 'ez_settings.mat');
            if isfile(settings_file)
                if ismember('roimat_settings', who('-file', settings_file))
                    load(fullfile(filepath, 'ez_settings.mat'), 'roimat_settings');
                    app.roimat = roimat_settings;
                    write_roimat(app)
                end
            end
            
            warning_text = '';
            if ~license('test', 'signal_toolbox')
                warning_text = [warning_text newline 'Signal Processing Toolbox'];
            end
            if ~license('test', 'image_toolbox')
                warning_text = [warning_text newline '    Image Processing Toolbox'];
            end
            if ~license('test', 'distrib_computing_toolbox')
                warning_text = [warning_text newline '    Parallel Computing Toolbox'];
            end
            if ~license('test', 'statistics_toolbox')
                warning_text = [warning_text newline '    Statistics and Machine Learning Toolbox'];
            end
            if ~isempty(warning_text)
                warning_text = ['It seems like you do not have the following MATLAB toolboxes installed: ' newline warning_text newline newline 'ROI Matching will not work without these toolboxes.'];
                msgbox(warning_text, 'Warning');
            end
            
            if ~exist('CNMFSetParms.m', 'file')
                warning_text = 'Cannot find CaImAn on MATLAB path. Please make sure you download CaImAn-MATLAB from https://github.com/porteralab/CaImAn-MATLAB and add it to MATLAB path. ROI Matching will not work without doing this.';
                msgbox(warning_text, 'Warning');
            end
            if ~exist('NoRMCorreSetParms.m', 'file')
                warning_text = 'Cannot find NoRMCorre on MATLAB path. Please make sure you download NoRMCorre from https://github.com/porteralab/NoRMCorre and add it to MATLAB path. ROI Matching will not work without doing this.';
                msgbox(warning_text, 'Warning');
            end
        end

        % Button pushed function: AddFilesButton
        function AddFilesButtonPushed(app, event)
            [add_file, add_filepath] = uigetfile('*.mat; *.txt', 'Choose data files to be processed.', 'MultiSelect', 'on');
            figure(app.EZROIMatchingUIFigure) % workaround to keep focus at UI window
            
            if ~iscell(add_file) && ~ischar(add_file)
                return
            end
            
            % Checks to see if only one item was added
            if ~iscell(add_file); add_file = cellstr(add_file); end
            
            % Check for repeats, if not, add to list
            i = 0;
            while i < length(add_file)
                i = i + 1;
                full_add_file = [add_filepath add_file{i}];
                
                if sum(ismember(app.DataFilesListBox.Items, full_add_file)) > 0 % If repeats, warning_text update
                    warning_text = ['File: ' full_add_file ' is already on the list.'];
                    msgbox(warning_text, 'Warning');
                elseif strcmp(full_add_file(end-3:end), '.txt')
                    fid = fopen(full_add_file);
                    while 1
                        tline = fgetl(fid);
                        if ~ischar(tline), break, end
                        if isfile(tline)
                            [~, ~, file_ext] = fileparts(tline);
                            if strcmp(file_ext, '.mat')
                                [~, lfname, lfext] = fileparts(tline);
                                add_file = horzcat(add_file, cellstr([lfname lfext]));
                            end
                        end
                    end
                    fclose(fid);
                else
                    app.DataFilesListBox.Items = horzcat(app.DataFilesListBox.Items, cellstr(full_add_file)); drawnow
                end
            end
        end

        % Button pushed function: ClearListButton
        function ClearListButtonPushed(app, event)
            app.DataFilesListBox.Items = {}; drawnow
        end

        % Button pushed function: RunROIMatchingButton
        function RunROIMatchingButtonPushed(app, event)
            % Check data files are valid
            num_sessions = length(app.DataFilesListBox.Items);
            if isempty(app.DataFilesListBox.Items)
                warning_text = 'No file has been added yet!';
                msgbox(warning_text, 'Warning');
                return
            elseif num_sessions < 2
                warning_text = 'Only one data file added. We need at least two for ROI Matching!';
                msgbox(warning_text, 'Warning');
                return
            end
            for i = 1:num_sessions
                if ~ismember('options', who('-file', app.DataFilesListBox.Items{i})) || ~ismember('F_raw', who('-file', app.DataFilesListBox.Items{i}))
                    warning_text = ['Data file ' app.DataFilesListBox.Items{i} ' is invalid. Did you forget to run ROI Detection first?'];
                    msgbox(warning_text, 'Warning')
                    return
                end
            end
            
            % disable run button during running
            app.RunROIMatchingButton.Enable = 'off'; drawnow
            
            app.DataFilesListBox.Value = app.DataFilesListBox.Items(1); % move selection to first position
            
            parse_roimat(app)
            
            % Load ROI data
            for i = 1:num_sessions
                app.roidata{i} = load(app.DataFilesListBox.Items{i});
            end
            
            options = app.roidata{1}.options;
            options.dist_overlap_thr = app.roimat.overlap_thr; % override with GUI setting
            options.plot_reg = app.roimat.plot_result; % override with GUI setting
            
            if isfield(app.roidata{1}, 'options_mc')
                align_flag = true;
                options_mc = app.roidata{1}.options_mc;
                options_mc.correct_bidir = false;
                for i = 1:num_sessions
                    templates{i} = app.roidata{i}.template;
                end
            else
                align_flag = false;
                warning_text = 'No motion correction information found. Skipping alignment...Matching results will not be plotted.';
                msgbox(warning_text, 'Warning')
                options.plot_reg = false;
            end
            
            for i = 1:num_sessions
                if app.roimat.use_refined && ~isfield(app.roidata{i}, 'F_raw_refined')
                    warning_text = ['Unable to load refined ROI data from ' app.DataFilesListBox.Items{i} '. Will proceed to load unrefined data instead.'];
                    msgbox(warning_text, 'Warning')
                    app.roimat.use_refined = false;
                end
                if app.roimat.use_refined && isfield(app.roidata{i}, 'F_raw_refined')
                    A{i} = app.roidata{i}.spatial_comp_refined;
                    F_raw{i} = app.roidata{i}.F_raw_refined;
                    F_inferred{i} = app.roidata{i}.F_inferred_refined;
                    S_deconv{i} = app.roidata{i}.S_deconv_refined;
                    Z_mod{i} = app.roidata{i}.Z_mod_refined;
                    ROI_center{i} = app.roidata{i}.ROI_center_refined;
                else
                    A{i} = app.roidata{i}.spatial_comp;
                    F_raw{i} = app.roidata{i}.F_raw;
                    F_inferred{i} = app.roidata{i}.F_inferred;
                    S_deconv{i} = app.roidata{i}.S_deconv;
%                    Z_mod{i} = app.roidata{i}.Z_mod;
                    ROI_center{i} = app.roidata{i}.ROI_center;
                end
            end
            
            % Run ROI registration
            A_union = A{1};
            matchings{1} = 1:size(A{1},2);
            
            for i = 2:num_sessions
                if align_flag
                    [matched_ROIs, nonmatched_1, ~, ~, ~, A_un] = register_ROIs(...
                        A{i}, ...
                        A_union, ...
                        options, ...
                        templates{i}, ...
                        templates{i - 1}, ...
                        options_mc);
                else
                    [matched_ROIs, nonmatched_1, ~, ~, ~, A_un] = register_ROIs(...
                        A{i}, ...
                        A_union, ...
                        options);
                end
                A_union = A_un;
                A_union(:, matched_ROIs(:, 2)) = A{i}(:, matched_ROIs(:, 1));
                A_union = [A_union, A{i}(:, nonmatched_1)];
                new_match = zeros(1, size(A{i}, 2));
                new_match(matched_ROIs(:, 1)) = matched_ROIs(:, 2);
                new_match(nonmatched_1) = size(A_un, 2) + 1:size(A_union, 2);
                matchings{i} = new_match;
            end
            
            assignments = NaN(size(A_union, 2), num_sessions);
            for i = 1:num_sessions
                assignments(matchings{i}, i) = 1:length(matchings{i});
            end
            
            matched_only = [];
            for cur_roi = 1:size(A{1}, 2) %only need to check first number of rows of the first session
                if sum(~isnan(assignments(cur_roi, :))) == num_sessions %all sessions are matched
                    matched_only = vertcat(matched_only, assignments(cur_roi, :));
                end
            end
            
            % Save data
            roimat_settings = app.roimat;
            
            for i = 1:num_sessions
                spatial_comp_matched = A{i}(:, (matched_only(:, i)));
                F_raw_matched = F_raw{i}((matched_only(:, i)), :);
                F_inferred_matched = F_inferred{i}((matched_only(:, i)), :);
                S_deconv_matched = S_deconv{i}((matched_only(:, i)), :);
                ROI_center_matched = ROI_center{i}((matched_only(:, i)), :);
                
                filename_mat = app.DataFilesListBox.Items{i};
                if ismember('F_raw_matched', who('-file', filename_mat))
                    msgbox(['It looks like file ' filename_mat ' already have saved ROI matching data. Will improvise a different file name to avoid overwriting.'], 'Warning')
                    filename_mat = [filename_mat(1:end-4) '_' datestr(now, 30) '.mat'];
                    save_new_file = true;
                else
                    save_new_file = false;
                end
                
                if app.roimat.use_refined
                    Z_mod_matched = Z_mod{i}((matched_only(:, i)), :);
                    if save_new_file
                        save(filename_mat, 'spatial_comp_matched', 'F_raw_matched', 'F_inferred_matched', 'S_deconv_matched', 'ROI_center_matched', 'Z_mod_matched', 'roimat_settings')
                    else
                        save(filename_mat, 'spatial_comp_matched', 'F_raw_matched', 'F_inferred_matched', 'S_deconv_matched', 'ROI_center_matched', 'Z_mod_matched', 'roimat_settings', '-append')
                    end
                else
                    if save_new_file
                        save(filename_mat, 'spatial_comp_matched', 'F_raw_matched', 'F_inferred_matched', 'S_deconv_matched', 'ROI_center_matched', 'roimat_settings')
                    else
                        save(filename_mat, 'spatial_comp_matched', 'F_raw_matched', 'F_inferred_matched', 'S_deconv_matched', 'ROI_center_matched', 'roimat_settings', '-append')
                    end
                end
            end
            
            app.RunROIMatchingButton.Enable = 'on'; drawnow
        end

        % Close request function: EZROIMatchingUIFigure
        function EZROIMatchingUIFigureCloseRequest(app, event)
            parse_roimat(app)
            roimat_settings = app.roimat;
            filepath = fileparts([mfilename('fullpath') '.m']);
            if isfile(fullfile(filepath, 'ez_settings.mat'))
                save(fullfile(filepath, 'ez_settings.mat'), 'roimat_settings', '-append')
            else
                save(fullfile(filepath, 'ez_settings.mat'), 'roimat_settings')
            end
            delete(app)
        end

        % Button pushed function: MoveupButton
        function MoveupButtonPushed(app, event)
            [~, cur_pos] = ismember(app.DataFilesListBox.Value, app.DataFilesListBox.Items);
            if cur_pos ~= 1
                temp = app.DataFilesListBox.Items{cur_pos - 1};
                app.DataFilesListBox.Items{cur_pos - 1} = app.DataFilesListBox.Items{cur_pos};
                app.DataFilesListBox.Items{cur_pos} = temp;
            end
        end

        % Button pushed function: MovedownButton
        function MovedownButtonPushed(app, event)
            [~, cur_pos] = ismember(app.DataFilesListBox.Value, app.DataFilesListBox.Items);
            if cur_pos ~= length(app.DataFilesListBox.Items)
                temp = app.DataFilesListBox.Items{cur_pos + 1};
                app.DataFilesListBox.Items{cur_pos + 1} = app.DataFilesListBox.Items{cur_pos};
                app.DataFilesListBox.Items{cur_pos} = temp;
            end
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            [filename, filepath] = uiputfile('*.mat');
            figure(app.EZROIMatchingUIFigure) % workaround to keep focus at UI window
            
            % Check if anything was selected
            if filename == 0
                return
            end
            
            parse_roimat(app)
            roimat_settings = app.roimat;
            
            full_filename = [filepath filename];
            save(full_filename, 'roimat_settings');
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.mat');
            figure(app.EZROIMatchingUIFigure) % workaround to keep focus at UI window
            
            % Check if anything was selected
            if filename == 0
                return
            end
            
            full_filename = [filepath filename];
            
            % Check if file is valid
            if ~ismember('roimat_settings', who('-file', full_filename))
                warning_text = 'The selected file is not a valid settings file.';
                msgbox(warning_text, 'Warning')
                return
            end
            
            load(full_filename, 'roimat_settings');
            app.roimat = roimat_settings;
            write_roimat(app)
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web('https://github.com/porteralab/EZcalcium/wiki/ROI-Matching')
        end

        % Button pushed function: UseRefinedROIsHelpButton
        function UseRefinedROIsHelpButtonPushed(app, event)
            msgbox("Use Refined ROIs determines whether to use refined ROIs, i.e. *_refined variables in the data file for ROI matching.", "Help", 'replace')
        end

        % Button pushed function: PlotMatchingResultHelpButton
        function PlotMatchingResultHelpButtonPushed(app, event)
            msgbox("Plot Matching Result generates figures showing matched and non-matched ROIs between each pair of matchings.", "Help", 'replace')
        end

        % Button pushed function: OverlapThresholdHelpButton
        function OverlapThresholdHelpButtonPushed(app, event)
            msgbox("Overlap Threshold specifies the threshold for determine if one ROI is a subset of another.", "Help", 'replace')
        end

        % Button pushed function: LoadSaveHelpButton
        function LoadSaveHelpButtonPushed(app, event)
            msgbox("Save Settings allows you to manually save all settings under a specific file name of your choosing. Settings are saved as .mat files. Note that EZcalcium will automatically save the settings into the .mat data file, so manually saving the settings is usually not necessary. Load Settings allows you to load any previously saved settings. You can load the automatically generated .mat file (which is the same file that saves ROI Detection and ROI Refinement results) to check the settings used to generate the results.", "Help", 'replace')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EZROIMatchingUIFigure = uifigure('Visible', 'off');
            app.EZROIMatchingUIFigure.Name = 'EZ ROI Matching';
            app.EZROIMatchingUIFigure.CloseRequestFcn = createCallbackFcn(app, @EZROIMatchingUIFigureCloseRequest, true);
            app.ROIMatchingLabel = uilabel(app.EZROIMatchingUIFigure);
            app.ROIMatchingLabel.Text = 'ROI Matching';
            app.DataFilesLabel = uilabel(app.EZROIMatchingUIFigure);
            app.DataFilesLabel.Text = 'Data Files';
            app.DataFilesListBox = uilistbox(app.EZROIMatchingUIFigure);
            app.DataFilesListBox.Items = {};
            app.DataFilesListBox.Value = {};
            app.AddFilesButton = uibutton(app.EZROIMatchingUIFigure, 'push');
            app.AddFilesButton.ButtonPushedFcn = createCallbackFcn(app, @AddFilesButtonPushed, true);
            app.AddFilesButton.Text = {'Add Files...'; ''};
            app.MovedownButton = uibutton(app.EZROIMatchingUIFigure, 'push');
            app.MovedownButton.ButtonPushedFcn = createCallbackFcn(app, @MovedownButtonPushed, true);
            app.MovedownButton.Text = 'Move down';
            app.ClearListButton = uibutton(app.EZROIMatchingUIFigure, 'push');
            app.ClearListButton.ButtonPushedFcn = createCallbackFcn(app, @ClearListButtonPushed, true);
            app.ClearListButton.Text = 'Clear List';
            app.SettingsPanel = uipanel(app.EZROIMatchingUIFigure);
            app.UseRefinedROIsCheckBox = uicheckbox(app.SettingsPanel);
            app.UseRefinedROIsCheckBox.Text = 'Use Refined ROIs';
            app.UseRefinedROIsCheckBox.Value = true;
            app.OverlapThresholdLabel = uilabel(app.SettingsPanel);
            app.OverlapThresholdLabel.Text = 'Overlap Threshold';
            app.SaveSettingsButton = uibutton(app.SettingsPanel, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.LoadSettingsButton = uibutton(app.SettingsPanel, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.OverlapThresholdEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.OverlapThresholdEditField.Value = 0.8;
            app.UseRefinedROIsHelpButton = uibutton(app.SettingsPanel, 'push');
            app.UseRefinedROIsHelpButton.ButtonPushedFcn = createCallbackFcn(app, @UseRefinedROIsHelpButtonPushed, true);
            app.UseRefinedROIsHelpButton.Text = '?';
            app.PlotMatchingResultHelpButton = uibutton(app.SettingsPanel, 'push');
            app.PlotMatchingResultHelpButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMatchingResultHelpButtonPushed, true);
            app.PlotMatchingResultHelpButton.Text = '?';
            app.OverlapThresholdHelpButton = uibutton(app.SettingsPanel, 'push');
            app.OverlapThresholdHelpButton.ButtonPushedFcn = createCallbackFcn(app, @OverlapThresholdHelpButtonPushed, true);
            app.OverlapThresholdHelpButton.Text = '?';
            app.LoadSaveHelpButton = uibutton(app.SettingsPanel, 'push');
            app.LoadSaveHelpButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSaveHelpButtonPushed, true);
            app.LoadSaveHelpButton.Text = '?';
            app.PlotMatchingResultCheckBox = uicheckbox(app.SettingsPanel);
            app.PlotMatchingResultCheckBox.Text = 'Plot Matching Result';
            app.PlotMatchingResultCheckBox.Value = true;
            app.SettingsLabel = uilabel(app.EZROIMatchingUIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.RunROIMatchingButton = uibutton(app.EZROIMatchingUIFigure, 'push');
            app.RunROIMatchingButton.ButtonPushedFcn = createCallbackFcn(app, @RunROIMatchingButtonPushed, true);
            app.RunROIMatchingButton.Text = 'Run ROI Matching';
            app.HelpButton = uibutton(app.EZROIMatchingUIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.MoveupButton = uibutton(app.EZROIMatchingUIFigure, 'push');
            app.MoveupButton.ButtonPushedFcn = createCallbackFcn(app, @MoveupButtonPushed, true);
            app.MoveupButton.Text = 'Move up';
            % Show the figure after all components are created
            app.EZROIMatchingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ez_roi_match

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.EZROIMatchingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.EZROIMatchingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EZROIMatchingUIFigure)
        end
    end
end